classdef RollerCoasterExample < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Image                     matlab.ui.control.Image
        CheckforPassengersButton  matlab.ui.control.Button
        TotalRidersLabel          matlab.ui.control.Label
        GatePositionKnob          matlab.ui.control.DiscreteKnob
        GatePositionKnobLabel     matlab.ui.control.Label
        WildBlueRollerCoasterRidesLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        a % Description
        s1
 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.a = arduino('COM6');
            app.s1 = servo(app.a,'D9', 'MinPulseDuration', 700*10^-6, 'MaxPulseDuration', 2300*10^-6);
            writePosition(app.s1, 0.5)

        end

        % Button pushed function: CheckforPassengersButton
        function CheckforPassengersButtonPushed(app, event)
                 totalNumRiders = 0; 
                 writePosition(app.s1, 0.5)
                     app.GatePositionKnob.Value = 'Gates Up';
    app.TotalRidersLabel.Text = num2str(totalNumRiders);

while totalNumRiders < 5

   sensor = readVoltage(app.a, 'A0'); 

if sensor < 3.0
    app.GatePositionKnob.Value = 'Gates Down';
    writePosition(app.s1, 1)
    totalNumRiders = totalNumRiders + 1;
    app.TotalRidersLabel.Text = num2str(totalNumRiders);
    pause(2);

else 
    app.GatePositionKnob.Value = 'Gates Up';
    writePosition(app.s1, 0.5)
    pause(2);
  
end %end of the while loop
end

app.GatePositionKnob.Value = 'Gates Locked';
writePosition(app.s1,0);
pause(1);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WildBlueRollerCoasterRidesLabel = uilabel(app.UIFigure);
            app.WildBlueRollerCoasterRidesLabel.Text = 'Wild Blue Roller Coaster Rides';
            app.GatePositionKnobLabel = uilabel(app.UIFigure);
            app.GatePositionKnobLabel.Text = 'Gate Position';
            app.GatePositionKnob = uiknob(app.UIFigure, 'discrete');
            app.GatePositionKnob.Items = {'Gates Up', 'Gates Down', 'Gates Locked'};
            app.GatePositionKnob.Value = 'Gates Up';
            app.TotalRidersLabel = uilabel(app.UIFigure);
            app.TotalRidersLabel.Text = 'Total Riders';
            app.CheckforPassengersButton = uibutton(app.UIFigure, 'push');
            app.CheckforPassengersButton.ButtonPushedFcn = createCallbackFcn(app, @CheckforPassengersButtonPushed, true);
            app.CheckforPassengersButton.Text = 'Check for Passengers';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Roller Coaster .jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RollerCoasterExample

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end