classdef temperature_check_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Image                     matlab.ui.control.Image
        ResultEditField           matlab.ui.control.EditField
        ResultEditFieldLabel      matlab.ui.control.Label
        DropDown                  matlab.ui.control.DropDown
        TakeTempA0Button          matlab.ui.control.Button
        TemperatureCheckAppLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        a % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.a = arduino();                                                                              % Connects the Arduino
            app.TakeTempA0Button.BackgroundColor = [0.30,0.75,0.93];     % Changes color of button after connection
        end

        % Button pushed function: TakeTempA0Button
        function TakeTempA0ButtonPushed(app, event)
                   volts = readVoltage(app.a,'A0');                                % Get value from the digital pin and store it in digital pin
                   R2=10000;                                                                   % the value of the precision resistor
                   temp0 = 298.15;                                                          % reference temperature (25 C), in Kelvin
                   res0 = 10000;                                                               % resistance at reference temperature, in Ohms
                   B = 3950;                                                                       % thermistor B parameter, in Kelvin
                   current=volts/R2;                                                          % calculate the current using Ohm's law
                   Resistance=(5-volts)/current;                                    % find the resistance of the thermistor
                   recip_temp = 1/temp0 + log(Resistance/res0)/B; % Calculate the reciprocal of the TempK
                   TempK = 1./recip_temp;                                             % Calculate Temp in Kelvin
           
           switch app.DropDown.Value                                   % Identifying what to reference 
               
               case "Voltage"                                                                  % When the string is "Voltage" 
                   app.ResultEditField.Value=num2str(volts, 3);                % Tells the output field to use the value of volts  
               case "Celsius"                                                                 % When the string is "Celsius" 
                   TempC = TempK -273.15;                                         % Convert to Celsius 
                   app.ResultEditField.Value=num2str(TempC,3);           % Tells the output field to use the value of TempC
               case "Fahrenheit"                                                           % When the string is "Fahrenheit" 
                   TempF=((TempK -273.15)*1.8)+32;                       % Convert to Fahrenheit
                   app.ResultEditField.Value=num2str(TempF,3);          % Tells the output field to use the value of TempF 
           end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TemperatureCheckAppLabel = uilabel(app.UIFigure);
            app.TemperatureCheckAppLabel.Text = 'Temperature Check App';
            app.TakeTempA0Button = uibutton(app.UIFigure, 'push');
            app.TakeTempA0Button.ButtonPushedFcn = createCallbackFcn(app, @TakeTempA0ButtonPushed, true);
            app.TakeTempA0Button.Text = 'Take Temp (A0)';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Voltage', 'Fahrenheit', 'Celsius'};
            app.DropDown.Value = 'Voltage';
            app.ResultEditFieldLabel = uilabel(app.UIFigure);
            app.ResultEditFieldLabel.Text = 'Result';
            app.ResultEditField = uieditfield(app.UIFigure, 'text');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'TempImage.jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = temperature_check_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end